classdef GUI_Master < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RPSPASS          matlab.ui.Figure
        Menu             matlab.ui.container.Menu
        PreferencesMenu  matlab.ui.container.Menu
        Menu_2           matlab.ui.container.Menu
        SuccessMenu      matlab.ui.container.Menu
        HTML             matlab.ui.control.HTML
    end


    properties (Access = private)

    end

    properties (Access = public)
        SpikeInUsed
        SpikeInDiam
        SpikeInConc
        Version
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Version = "1.0.1";
            RPSPASS_Preference(app)
            softwareUpdate(app)
        end

        % Data changed function: HTML
        function HTMLDataChanged(app, event)
            try
                Output = jsondecode(app.HTML.Data);
                switch Output.Response
                    case 'Submit'

                        [app] = getSpikeInInfo(app, Output);

                        [status, message]=ImportFiles(app);
                        if status == true
                            app.HTML.HTMLSource = 'Success.html';
                        elseif status == false
                            app.HTML.HTMLSource = 'Unsuccessful.html';
                        end
                    case 'Restart'
                        app.HTML.HTMLSource = 'StartMenu.html';
                    case 'Save'
                        app.HTML.HTMLSource = 'StartMenu.html';
                        pause(0.1)
                        PrefOverwrite(app)
                end
            catch ME

                % Catch and report error
                errorMessage = sprintf('Error %s() %d.\n\nError Message:\n%s', ...
                    ME.stack(1).name, ME.stack(1).line, ME.message);
                ErrorLog(app, errorMessage)
                app.HTML.HTMLSource = 'Unsuccessful.html';
            end
        end

        % Menu selected function: PreferencesMenu
        function PreferencesMenuSelected(app, event)

            pref = getprefRPSPASS('RPSPASS');
            app.HTML.Data = jsonencode(pref);
            app.HTML.HTMLSource = 'preferences.html';

        end

        % Menu selected function: Menu_2
        function Menu_2Selected(app, event)
            app.HTML.HTMLSource = 'StartMenu.html';
        end

        % Menu selected function: SuccessMenu
        function SuccessMenuSelected(app, event)
            app.HTML.HTMLSource = 'Unsuccessful.html';
            %             app.HTML.HTMLSource = 'Success.html';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RPSPASS = uifigure('Visible', 'off');
            app.RPSPASS.Name = 'RPSPASS';
            app.RPSPASS.Icon = 'RPS Logo.jpg';
            app.Menu = uimenu(app.RPSPASS);
            app.Menu.Text = 'Menu';
            app.PreferencesMenu = uimenu(app.Menu);
            app.PreferencesMenu.MenuSelectedFcn = createCallbackFcn(app, @PreferencesMenuSelected, true);
            app.PreferencesMenu.Text = 'Preferences';
            app.Menu_2 = uimenu(app.Menu);
            app.Menu_2.MenuSelectedFcn = createCallbackFcn(app, @Menu_2Selected, true);
            app.Menu_2.Text = 'Menu';
            app.SuccessMenu = uimenu(app.Menu);
            app.SuccessMenu.MenuSelectedFcn = createCallbackFcn(app, @SuccessMenuSelected, true);
            app.SuccessMenu.Text = 'Success';
            app.HTML = uihtml(app.RPSPASS);
            app.HTML.HTMLSource = 'StartMenu.html';
            app.HTML.DataChangedFcn = createCallbackFcn(app, @HTMLDataChanged, true);
            % Show the figure after all components are created
            app.RPSPASS.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_Master

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.RPSPASS)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.RPSPASS)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RPSPASS)
        end
    end
end