classdef GUI_Acquisition < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AcquisitionUI        matlab.ui.Figure
        Panel                matlab.ui.container.Panel
        GridLayout           matlab.ui.container.GridLayout
        Panel_2              matlab.ui.container.Panel
        Switch               matlab.ui.control.Switch
        LiveLamp             matlab.ui.control.Lamp
        SampleName           matlab.ui.control.DropDown
        LiveSampleLabel      matlab.ui.control.Label
        TimeStatPlot         matlab.ui.control.UIAxes
        DiamTTimePlot        matlab.ui.control.UIAxes
        DiamTimePlot         matlab.ui.control.UIAxes
        ContextMenu          matlab.ui.container.ContextMenu
        ForceUpdateMenu      matlab.ui.container.Menu
        ColormapMenu         matlab.ui.container.Menu
        jetMenu              matlab.ui.container.Menu
        turboMenu            matlab.ui.container.Menu
        hotMenu              matlab.ui.container.Menu
        ColorscaleMenu       matlab.ui.container.Menu
        linearMenu           matlab.ui.container.Menu
        logMenu              matlab.ui.container.Menu
        GridsMenu            matlab.ui.container.Menu
        XMajorMenu           matlab.ui.container.Menu
        XMinorMenu           matlab.ui.container.Menu
        YMajorMenu           matlab.ui.container.Menu
        YMinorMenu           matlab.ui.container.Menu
        ResolutionMenu       matlab.ui.container.Menu
        ResMenu_3            matlab.ui.container.Menu
        ContextMenu2         matlab.ui.container.ContextMenu
        ForceUpdateMenu_2    matlab.ui.container.Menu
        StatsMenu            matlab.ui.container.Menu
        TotalEventsMenu      matlab.ui.container.Menu
        MeanTotalEventsMenu  matlab.ui.container.Menu
        AcqVolumeMenu        matlab.ui.container.Menu
        MeanAcqVolumeMenu    matlab.ui.container.Menu
        ResMenu_2            matlab.ui.container.Menu
        ResMenu_1            matlab.ui.container.Menu
    end


    properties (Access = public)
        LiveAcquisition = [];
        myTimer = [];
        Resolution = 256;
        TimeMax = 100;
        TTimeMax = 100;
        DiamMax = 400;
        TimeEdges = linspace(0,100, 256)
        TTimeEdges = linspace(0,100, 256)
        DiamEdges = linspace(0,400, 256)
        Colormap = 'jet';
        Colorscaling = 'linear';
    end

    methods (Access = private)


        function UpdateAllPlots(app)
            %             UpdatePlottingPrefs(app)
            Live_File_Acquisition(app)
        end

        function deselectRsolutionOptions(app)
            app.ResMenu_1.Checked = 'off';
            app.ResMenu_2.Checked = 'off';
            app.ResMenu_3.Checked = 'off';
        end

        function deselectColormapOptions(app)
            app.jetMenu.Checked = 'off';
            app.turboMenu.Checked = 'off';
            app.hotMenu.Checked = 'off';
        end
    end

    methods (Access = public)

        function UpdatePlottingPrefs(app)

            app.TimeEdges = linspace(0,app.TimeMax, app.Resolution);
            app.TTimeEdges = linspace(0,app.TTimeMax, app.Resolution);
            app.DiamEdges = linspace(0,app.DiamMax, app.Resolution);

            % time vs. diameter plot preferences
            app.DiamTimePlot.XLim = [min(app.TimeEdges),max(app.TimeEdges)];
            app.DiamTimePlot.YLim = [min(app.DiamEdges),max(app.DiamEdges)];
            app.DiamTimePlot.XMinorTick = 'on';
            app.DiamTimePlot.XAxis.MinorTickValues = 0:10:max(app.TimeEdges);
            xticks(app.DiamTimePlot,'auto')
            yticks(app.DiamTimePlot,'auto')
            app.DiamTimePlot.Toolbar.Visible = 'off';
            app.DiamTimePlot.XGrid = app.XMajorMenu.Checked;
            app.DiamTimePlot.XMinorGrid = app.XMinorMenu.Checked;
            app.DiamTimePlot.YGrid = app.YMajorMenu.Checked;
            app.DiamTimePlot.YMinorGrid = app.YMinorMenu.Checked;

            % transit time vs. diameter plot preferences
            app.DiamTTimePlot.XLim = [min(app.TTimeEdges),max(app.TTimeEdges)];
            app.DiamTTimePlot.YLim = [min(app.DiamEdges),max(app.DiamEdges)];
            xticks(app.DiamTTimePlot,'auto')
            yticks(app.DiamTTimePlot,'auto')
            app.DiamTTimePlot.Toolbar.Visible = 'off';
            app.DiamTTimePlot.XGrid = app.XMajorMenu.Checked;
            app.DiamTTimePlot.XMinorGrid = app.XMinorMenu.Checked;
            app.DiamTTimePlot.YGrid = app.YMajorMenu.Checked;
            app.DiamTTimePlot.YMinorGrid = app.YMinorMenu.Checked;

            % time vs. stat
            app.TimeStatPlot.XLim = [min(app.TimeEdges),max(app.TimeEdges)];
            ylim(app.TimeStatPlot,'auto');
            xticks(app.TimeStatPlot,'auto')
            yticks(app.TimeStatPlot,'auto')
            app.TimeStatPlot.Toolbar.Visible = 'off';
            app.TimeStatPlot.XGrid = app.XMajorMenu.Checked;
            app.TimeStatPlot.XMinorGrid = app.XMinorMenu.Checked;
            app.TimeStatPlot.YGrid = app.YMajorMenu.Checked;
            app.TimeStatPlot.YMinorGrid = app.YMinorMenu.Checked;

        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.LiveAcquisition = true;
            app.myTimer = timer('Name','MyTimer',               ...
                'Period',5,                 ...
                'StartDelay',0,                 ...
                'TasksToExecute',inf,           ...
                'ExecutionMode','fixedSpacing', ...
                'StartFcn',@(~,~)Live_File_Acquisition(app),...
                'TimerFcn',@(~,~)Live_File_Acquisition(app));

            start(app.myTimer);


        end

        % Callback function
        function testButtonPushed(app, event)
            UpdateAllPlots(app)
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            switch app.Switch.Value
                case 'Off'
                    stop(app.myTimer);
                    app.LiveLamp.Color = "red";
                case 'On'
                    start(app.myTimer)
                    app.LiveLamp.Color = "green";
            end
        end

        % Value changed function: SampleName
        function SampleNameValueChanged(app, event)
            UpdateAllPlots(app)

        end

        % Menu selected function: ResMenu_1
        function ResMenu_1Selected(app, event)
            deselectRsolutionOptions(app)
            app.ResMenu_1.Checked = "on";

            app.Resolution = 128;
            UpdateAllPlots(app)
        end

        % Menu selected function: ResMenu_2
        function ResMenu_2Selected(app, event)
            deselectRsolutionOptions(app)
            app.ResMenu_2.Checked = "on";
            app.Resolution = 256;
            UpdateAllPlots(app)
        end

        % Menu selected function: ResMenu_3
        function ResMenu_3Selected(app, event)
            deselectRsolutionOptions(app)
            app.ResMenu_3.Checked = "on";
            app.Resolution = 512;
            UpdateAllPlots(app)
        end

        % Menu selected function: ForceUpdateMenu, ForceUpdateMenu_2
        function ForceUpdateMenuSelected(app, event)
            UpdateAllPlots(app)
        end

        % Menu selected function: jetMenu
        function jetMenuSelected(app, event)
            app.Colormap = 'jet';
            deselectColormapOptions(app)
            app.jetMenu.Checked = 'on';
            UpdateAllPlots(app)

        end

        % Menu selected function: turboMenu
        function turboMenuSelected(app, event)
            app.Colormap = 'turbo';
            deselectColormapOptions(app)
            app.turboMenu.Checked = 'on';
            UpdateAllPlots(app)

        end

        % Menu selected function: hotMenu
        function hotMenuSelected(app, event)
            app.Colormap = 'hot';
            deselectColormapOptions(app)
            app.hotMenu.Checked = 'on';
            UpdateAllPlots(app)

        end

        % Menu selected function: linearMenu
        function linearMenuSelected(app, event)
            app.Colorscaling = 'linear';
            app.logMenu.Checked = 'off';
            app.linearMenu.Checked = 'on';
            UpdateAllPlots(app)
        end

        % Menu selected function: logMenu
        function logMenuSelected(app, event)
            app.Colorscaling = 'log';
            app.logMenu.Checked = 'on';
            app.linearMenu.Checked = 'off';
            UpdateAllPlots(app)
        end

        % Menu selected function: XMajorMenu
        function XMajorMenuSelected(app, event)
            switch app.XMajorMenu.Checked
                case 'on'
                    app.XMajorMenu.Checked = 'off';
                case 'off'
                    app.XMajorMenu.Checked = 'on';
            end
             UpdateAllPlots(app)
        end

        % Menu selected function: XMinorMenu
        function XMinorMenuSelected(app, event)
            switch app.XMinorMenu.Checked
                case 'on'
                    app.XMinorMenu.Checked = 'off';
                case 'off'
                    app.XMinorMenu.Checked = 'on';
            end
             UpdateAllPlots(app)
        end

        % Menu selected function: YMajorMenu
        function YMajorMenuSelected(app, event)
            switch app.YMajorMenu.Checked
                case 'on'
                    app.YMajorMenu.Checked = 'off';
                case 'off'
                    app.YMajorMenu.Checked = 'on';
            end
            UpdateAllPlots(app)
        end

        % Menu selected function: YMinorMenu
        function YMinorMenuSelected(app, event)
            switch app.YMinorMenu.Checked
                case 'on'
                    app.YMinorMenu.Checked = 'off';
                case 'off'
                    app.YMinorMenu.Checked = 'on';
            end
             UpdateAllPlots(app)
        end

        % Menu selected function: AcqVolumeMenu
        function AcqVolumeMenuSelected(app, event)
            switch app.AcqVolumeMenu.Checked
                case 'on'
                    app.AcqVolumeMenu.Checked = 'off';
                case 'off'
                    app.AcqVolumeMenu.Checked = 'on';
            end
             UpdateAllPlots(app)
        end

        % Menu selected function: MeanTotalEventsMenu
        function MeanTotalEventsMenuSelected(app, event)
            switch app.MeanTotalEventsMenu.Checked
                case 'on'
                    app.MeanTotalEventsMenu.Checked = 'off';
                case 'off'
                    app.MeanTotalEventsMenu.Checked = 'on';
            end
             UpdateAllPlots(app)
        end

        % Menu selected function: TotalEventsMenu
        function TotalEventsMenuSelected(app, event)
            switch app.TotalEventsMenu.Checked
                case 'on'
                    app.TotalEventsMenu.Checked = 'off';
                case 'off'
                    app.TotalEventsMenu.Checked = 'on';
            end
             UpdateAllPlots(app)
        end

        % Menu selected function: MeanAcqVolumeMenu
        function MeanAcqVolumeMenuSelected(app, event)
             switch app.MeanAcqVolumeMenu.Checked
                case 'on'
                    app.MeanAcqVolumeMenu.Checked = 'off';
                case 'off'
                    app.MeanAcqVolumeMenu.Checked = 'on';
            end
             UpdateAllPlots(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.AcquisitionUI = uifigure('Visible', 'off');
            app.AcquisitionUI.Name = 'RPSPASS Live Acquisition';
            app.AcquisitionUI.Icon = fullfile(pathToMLAPP, 'HTML', 'Images', 'RPS Logo.jpg');
            app.Panel = uipanel(app.AcquisitionUI);
            app.GridLayout = uigridlayout(app.Panel);
            app.DiamTimePlot = uiaxes(app.GridLayout);
            xlabel(app.DiamTimePlot, 'Time (secs)')
            ylabel(app.DiamTimePlot, 'Diameter (nm)')
            app.DiamTimePlot.YLim = [0 1];
            app.DiamTimePlot.XTick = [];
            app.DiamTimePlot.YTick = [];
            app.DiamTTimePlot = uiaxes(app.GridLayout);
            xlabel(app.DiamTTimePlot, 'Transit Time (µs)')
            ylabel(app.DiamTTimePlot, 'Diameter (nm)')
            app.DiamTTimePlot.XLim = [0 100];
            app.DiamTTimePlot.YLim = [0 400];
            app.DiamTTimePlot.XTick = [];
            app.DiamTTimePlot.YTick = [];
            app.TimeStatPlot = uiaxes(app.GridLayout);
            xlabel(app.TimeStatPlot, 'Time (secs)')
            ylabel(app.TimeStatPlot, 'Stat')
            app.TimeStatPlot.XLim = [0 100];
            app.TimeStatPlot.YLim = [0 400];
            app.TimeStatPlot.XTick = [];
            app.TimeStatPlot.YTick = [];
            app.Panel_2 = uipanel(app.GridLayout);
            app.LiveSampleLabel = uilabel(app.Panel_2);
            app.LiveSampleLabel.Text = 'Live Sample Selector';
            app.SampleName = uidropdown(app.Panel_2);
            app.SampleName.Items = {};
            app.SampleName.ValueChangedFcn = createCallbackFcn(app, @SampleNameValueChanged, true);
            app.SampleName.Value = {};
            app.LiveLamp = uilamp(app.Panel_2);
            app.Switch = uiswitch(app.Panel_2, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'On';
            app.ContextMenu = uicontextmenu(app.AcquisitionUI);
            app.ForceUpdateMenu = uimenu(app.ContextMenu);
            app.ForceUpdateMenu.MenuSelectedFcn = createCallbackFcn(app, @ForceUpdateMenuSelected, true);
            app.ForceUpdateMenu.Text = 'Force Update';
            app.ColormapMenu = uimenu(app.ContextMenu);
            app.ColormapMenu.Text = 'Colormap';
            app.jetMenu = uimenu(app.ColormapMenu);
            app.jetMenu.MenuSelectedFcn = createCallbackFcn(app, @jetMenuSelected, true);
            app.jetMenu.Checked = 'on';
            app.jetMenu.Text = 'jet';
            app.turboMenu = uimenu(app.ColormapMenu);
            app.turboMenu.MenuSelectedFcn = createCallbackFcn(app, @turboMenuSelected, true);
            app.turboMenu.Text = 'turbo';
            app.hotMenu = uimenu(app.ColormapMenu);
            app.hotMenu.MenuSelectedFcn = createCallbackFcn(app, @hotMenuSelected, true);
            app.hotMenu.Text = 'hot';
            app.ColorscaleMenu = uimenu(app.ContextMenu);
            app.ColorscaleMenu.Text = 'Colorscale';
            app.linearMenu = uimenu(app.ColorscaleMenu);
            app.linearMenu.MenuSelectedFcn = createCallbackFcn(app, @linearMenuSelected, true);
            app.linearMenu.Checked = 'on';
            app.linearMenu.Text = 'linear';
            app.logMenu = uimenu(app.ColorscaleMenu);
            app.logMenu.MenuSelectedFcn = createCallbackFcn(app, @logMenuSelected, true);
            app.logMenu.Text = 'log';
            app.GridsMenu = uimenu(app.ContextMenu);
            app.GridsMenu.Text = 'Grids';
            app.XMajorMenu = uimenu(app.GridsMenu);
            app.XMajorMenu.MenuSelectedFcn = createCallbackFcn(app, @XMajorMenuSelected, true);
            app.XMajorMenu.Checked = 'on';
            app.XMajorMenu.Text = 'X Major';
            app.XMinorMenu = uimenu(app.GridsMenu);
            app.XMinorMenu.MenuSelectedFcn = createCallbackFcn(app, @XMinorMenuSelected, true);
            app.XMinorMenu.Checked = 'on';
            app.XMinorMenu.Text = 'X Minor';
            app.YMajorMenu = uimenu(app.GridsMenu);
            app.YMajorMenu.MenuSelectedFcn = createCallbackFcn(app, @YMajorMenuSelected, true);
            app.YMajorMenu.Checked = 'on';
            app.YMajorMenu.Text = 'Y Major';
            app.YMinorMenu = uimenu(app.GridsMenu);
            app.YMinorMenu.MenuSelectedFcn = createCallbackFcn(app, @YMinorMenuSelected, true);
            app.YMinorMenu.Checked = 'on';
            app.YMinorMenu.Text = 'Y Minor';
            app.ResolutionMenu = uimenu(app.ContextMenu);
            app.ResolutionMenu.Text = 'Resolution';
            app.ResMenu_1 = uimenu(app.ResolutionMenu);
            app.ResMenu_1.MenuSelectedFcn = createCallbackFcn(app, @ResMenu_1Selected, true);
            app.ResMenu_1.Text = '128';
            app.ResMenu_2 = uimenu(app.ResolutionMenu);
            app.ResMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ResMenu_2Selected, true);
            app.ResMenu_2.Checked = 'on';
            app.ResMenu_2.Text = '256';
            app.ResMenu_3 = uimenu(app.ResolutionMenu);
            app.ResMenu_3.MenuSelectedFcn = createCallbackFcn(app, @ResMenu_3Selected, true);
            app.ResMenu_3.Text = '512';
            % Assign app.ContextMenu
            app.DiamTimePlot.ContextMenu = app.ContextMenu;
            app.AcquisitionUI.ContextMenu = app.ContextMenu;
            app.DiamTTimePlot.ContextMenu = app.ContextMenu;
            app.SampleName.ContextMenu = app.ContextMenu;
            app.Panel_2.ContextMenu = app.ContextMenu;
            app.ContextMenu2 = uicontextmenu(app.AcquisitionUI);
            app.ForceUpdateMenu_2 = uimenu(app.ContextMenu2);
            app.ForceUpdateMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ForceUpdateMenuSelected, true);
            app.ForceUpdateMenu_2.Text = 'Force Update';
            app.StatsMenu = uimenu(app.ContextMenu2);
            app.StatsMenu.Text = 'Stats';
            app.TotalEventsMenu = uimenu(app.StatsMenu);
            app.TotalEventsMenu.MenuSelectedFcn = createCallbackFcn(app, @TotalEventsMenuSelected, true);
            app.TotalEventsMenu.Text = 'Total Events';
            app.MeanTotalEventsMenu = uimenu(app.StatsMenu);
            app.MeanTotalEventsMenu.MenuSelectedFcn = createCallbackFcn(app, @MeanTotalEventsMenuSelected, true);
            app.MeanTotalEventsMenu.Text = 'Mean Total Events';
            app.AcqVolumeMenu = uimenu(app.StatsMenu);
            app.AcqVolumeMenu.MenuSelectedFcn = createCallbackFcn(app, @AcqVolumeMenuSelected, true);
            app.AcqVolumeMenu.Text = 'Acq Volume';
            app.MeanAcqVolumeMenu = uimenu(app.StatsMenu);
            app.MeanAcqVolumeMenu.MenuSelectedFcn = createCallbackFcn(app, @MeanAcqVolumeMenuSelected, true);
            app.MeanAcqVolumeMenu.Text = 'Mean Acq Volume';
            % Assign app.ContextMenu2
            app.TimeStatPlot.ContextMenu = app.ContextMenu2;
            % Show the figure after all components are created
            app.AcquisitionUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_Acquisition

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.AcquisitionUI)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.AcquisitionUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AcquisitionUI)
        end
    end
end